classdef France < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        readyLabel                matlab.ui.control.Label
        InitButton                matlab.ui.control.Button
        TextArea                  matlab.ui.control.TextArea
        InitLabel                 matlab.ui.control.Label
        InitareaEditField         matlab.ui.control.EditField
        InitareaEditFieldLabel    matlab.ui.control.Label
        CalculateButton           matlab.ui.control.Button
        TollweightSlider          matlab.ui.control.Slider
        TollweightSliderLabel     matlab.ui.control.Label
        TollsmartNavigationLabel  matlab.ui.control.Label
        DestEditField             matlab.ui.control.EditField
        DestEditFieldLabel        matlab.ui.control.Label
        StartEditField            matlab.ui.control.EditField
        StartEditFieldLabel       matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        RoutePanel                matlab.ui.container.Panel
        ContextMenu               matlab.ui.container.ContextMenu
        Menu                      matlab.ui.container.Menu
        Menu2                     matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            start_address = string(app.StartEditField.Value);
            dest_address = string(app.DestEditField.Value);
            [lat_lon_route,map_limit,start_ziel] = main(start_address,dest_address);
            gx = geoaxes(app.RoutePanel);
            geoplot(gx,lat_lon_route(:,1),lat_lon_route(:,2), ...
                start_ziel(1),start_ziel(2),'*', ...
                start_ziel(3),start_ziel(4),'*',LineWidth=3);
            text(gx,start_ziel(1),start_ziel(2),'start','Color','r','FontSize',15)
            text(gx,start_ziel(3),start_ziel(4),'dest','Color','r','FontSize',15)
            geolimits(gx,[map_limit(1),map_limit(2)],[map_limit(3),map_limit(4)]);
            geobasemap(gx,'streets');
        end

        % Button pushed function: InitButton
        function InitButtonPushed(app, event)
            init_address = string(app.InitareaEditField.Value);
            app.readyLabel.Text = 'loading...';
            drawnow
            init(init_address);
            app.readyLabel.Text = 'ready';
            drawnow
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartEditFieldLabel = uilabel(app.LeftPanel);
            app.StartEditFieldLabel.Text = 'Start:';
            app.StartEditField = uieditfield(app.LeftPanel, 'text');
            app.StartEditField.Placeholder = 'city or address';
            app.DestEditFieldLabel = uilabel(app.LeftPanel);
            app.DestEditFieldLabel.Text = 'Dest:';
            app.DestEditField = uieditfield(app.LeftPanel, 'text');
            app.DestEditField.Placeholder = 'city or address';
            app.TollsmartNavigationLabel = uilabel(app.LeftPanel);
            app.TollsmartNavigationLabel.Text = 'Tollsmart - Navigation';
            app.TollweightSliderLabel = uilabel(app.LeftPanel);
            app.TollweightSliderLabel.Text = 'Toll-weight';
            app.TollweightSlider = uislider(app.LeftPanel);
            app.CalculateButton = uibutton(app.LeftPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.InitareaEditFieldLabel = uilabel(app.LeftPanel);
            app.InitareaEditFieldLabel.Text = 'Init area';
            app.InitareaEditField = uieditfield(app.LeftPanel, 'text');
            app.InitareaEditField.Placeholder = 'city or address';
            app.InitLabel = uilabel(app.LeftPanel);
            app.InitLabel.Text = 'Init:';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Value = {'Enter a city to initalize the sourrounding area.'};
            app.InitButton = uibutton(app.LeftPanel, 'push');
            app.InitButton.ButtonPushedFcn = createCallbackFcn(app, @InitButtonPushed, true);
            app.InitButton.Text = 'Init';
            app.readyLabel = uilabel(app.LeftPanel);
            app.readyLabel.Text = 'ready';
            app.RightPanel = uipanel(app.GridLayout);
            app.RoutePanel = uipanel(app.RightPanel);
            app.RoutePanel.Title = 'Route';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = France

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end